classdef error_analysis_data_plotter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        ImportdataPanel           matlab.ui.container.Panel
        ImportdataButton          matlab.ui.control.Button
        DatapathEditFieldLabel    matlab.ui.control.Label
        DatapathEditField         matlab.ui.control.EditField
        ExaminedataPanel          matlab.ui.container.Panel
        ImporteddataListBoxLabel  matlab.ui.control.Label
        ImporteddataListBox       matlab.ui.control.ListBox
        DatainfoTextAreaLabel     matlab.ui.control.Label
        DatainfoTextArea          matlab.ui.control.TextArea
        RightPanel                matlab.ui.container.Panel
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        datas = {}; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportdataButton
        function ImportdataButtonPushed(app, event)
            data_path = app.DatapathEditField.Value;
            data_path = regexprep(data_path,'"','');
            data = load(data_path);
            app.datas{length(app.datas) + 1} = data;
            app.DatapathEditField.Value = '';
            for i = 1:length(app.datas)
                temp{i} = ['Data ' num2str(i)];
            end
            app.ImporteddataListBox.Items = temp;
        end

        % Value changed function: ImporteddataListBox
        function ImporteddataListBoxValueChanged(app, event)
            value = app.ImporteddataListBox.Value;
            value_idx = find(strcmp(app.ImporteddataListBox.Value,app.ImporteddataListBox.Items));
            disp(app.datas{value_idx})
            disp('')
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {486, 486};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ImportdataPanel = uipanel(app.LeftPanel);
            app.ImportdataPanel.Title = 'Import data';
            app.ImportdataButton = uibutton(app.ImportdataPanel, 'push');
            app.ImportdataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportdataButtonPushed, true);
            app.ImportdataButton.Text = 'Import data';
            app.DatapathEditFieldLabel = uilabel(app.ImportdataPanel);
            app.DatapathEditFieldLabel.Text = 'Data path';
            app.DatapathEditField = uieditfield(app.ImportdataPanel, 'text');
            app.ExaminedataPanel = uipanel(app.LeftPanel);
            app.ExaminedataPanel.Title = 'Examine data';
            app.ImporteddataListBoxLabel = uilabel(app.ExaminedataPanel);
            app.ImporteddataListBoxLabel.Text = 'Imported data';
            app.ImporteddataListBox = uilistbox(app.ExaminedataPanel);
            app.ImporteddataListBox.Items = {};
            app.ImporteddataListBox.ValueChangedFcn = createCallbackFcn(app, @ImporteddataListBoxValueChanged, true);
            app.ImporteddataListBox.Value = {};
            app.DatainfoTextAreaLabel = uilabel(app.LeftPanel);
            app.DatainfoTextAreaLabel.Text = 'Data info';
            app.DatainfoTextArea = uitextarea(app.LeftPanel);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = error_analysis_data_plotter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end